classdef drawtonum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RecognizeButton  matlab.ui.control.Button
        Label            matlab.ui.control.Label
        Spinner          matlab.ui.control.Spinner
        SpinnerLabel     matlab.ui.control.Label
        Button           matlab.ui.control.Button
        Image            matlab.ui.control.Image
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imds % loaded images
        net % NN
        selectedImage % n of selected image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            digitDatasetPath = fullfile(matlabroot,'toolbox','nnet','nndemos', ...
    'nndatasets','DigitDataset');

            app.imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            nNetFile = 'nnet.mat';
            if isfile(nNetFile)
                load(nNetFile, 'network')
                app.net = network;
            else
                numTrainFiles = 750;
                [imdsTrain,imdsValidation] = splitEachLabel(app.imds,numTrainFiles,'randomize');
    
                inputSize = [28 28 1];
                numClasses = 10;
                
                layers = [
                    imageInputLayer(inputSize)
                    convolution2dLayer(5,20)
                    batchNormalizationLayer
                    reluLayer
                    fullyConnectedLayer(numClasses)
                    softmaxLayer
                    classificationLayer];
    
                options = trainingOptions('sgdm', ...
                    'MaxEpochs',4, ...
                    'ValidationData',imdsValidation, ...
                    'ValidationFrequency',30, ...
                    'Verbose',false, ...
                    'Plots','training-progress');
    
                
                app.net = trainNetwork(imdsTrain,layers,options);
                network = app.net;
                save(nNetFile, 'network');
            end


            img = readimage(app.imds,1);
            app.Image.ImageSource = repmat(img, 1, 1, 3);
            
            app.Spinner.Limits = [0 9999];

            d = drawfreehand(app.UIAxes, 'color', 'green');
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.selectedImage = value + 1;
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            value = event.Value;
            imageSelected = readimage(app.imds, value + 1);
            app.Image.ImageSource = repmat(imageSelected, 1, 1, 3);
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            image = readimage(app.imds, app.selectedImage);
            n = classify(app.net, image);
            app.Label.Text = n;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '?';
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawtonum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end